classdef main < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                  matlab.ui.Figure
        MainGridLayout            matlab.ui.container.GridLayout
        TabGroup                  matlab.ui.container.TabGroup
        Tab1                      matlab.ui.container.Tab
        GridLayout                matlab.ui.container.GridLayout
        App1InputLabel            matlab.ui.control.Label
        App1InputImage            matlab.ui.control.Image
        App1OutputLabel           matlab.ui.control.Label
        App1OutputImage           matlab.ui.control.Image
        App1ControlPanel          matlab.ui.container.Panel
        App1OrderSlider           matlab.ui.control.Slider
        OrderSliderLabel          matlab.ui.control.Label
        App1CutoffSlider          matlab.ui.control.Slider
        CutoffSliderLabel         matlab.ui.control.Label
        App1FilterTypeDropDown    matlab.ui.control.DropDown
        FilterTypeDropDownLabel   matlab.ui.control.Label
        App1LoadPanel             matlab.ui.container.Panel
        App1FileName              matlab.ui.control.Label
        App1LoadFileButton        matlab.ui.control.Button
        App1FilterPassLabel       matlab.ui.control.Label
        App1FilterPassPanel       matlab.ui.container.ButtonGroup
        HighPassButton            matlab.ui.control.RadioButton
        LowPassButton             matlab.ui.control.RadioButton
        BrightnessContrastTab     matlab.ui.container.Tab
        GridLayout2               matlab.ui.container.GridLayout
        App2InputLabel            matlab.ui.control.Label
        App2InputImage            matlab.ui.control.Image
        App2OutputLabel           matlab.ui.control.Label
        App2OutputImage           matlab.ui.control.Image
        App2ControlPanel          matlab.ui.container.Panel
        App2ContrastSlider        matlab.ui.control.Slider
        ContrastLabel             matlab.ui.control.Label
        App2BrightnessSlider      matlab.ui.control.Slider
        BrightnessLabel           matlab.ui.control.Label
        App2LoadPanel             matlab.ui.container.Panel
        App2SaveFileButton        matlab.ui.control.Button
        App2FileName              matlab.ui.control.Label
        App2LoadFileButton        matlab.ui.control.Button
        HistogramEqualizationTab  matlab.ui.container.Tab
    end

    
    properties (Access = private)
        I = truecolorload('images\1mansion.png');
        I2 = truecolorload('images\3before.png');
        filename = "No File Selected"

        O = truecolorload('images\1mansion.png');
        O2 = truecolorload('images\3after.png');

        passtype = "low";
        filtertype = "ideal";
    end

    methods (Static)
    
        function result = rgbtogray(I)
            result = rgb2gray(I);
            result(:,:,2) = result(:,:,1);
            result(:,:,3) = result(:,:,1);
        end

    end
    
    methods (Access = private)
        
        function loadImage(app)
            % Load to I
            [file, path] = uigetfile('*');
            if file == 0
                return
            end
            filepath = fullfile(path, file);
            disp(filepath);
            app.filename = filepath;
            app.I = truecolorload(filepath);

            % Update image outputs
            app.updateBasedOnTab();
        end

        function saveImage(app)
            % Get file name
            [file, path] = uiputfile({ ...
                '*.bmp', 'BMP files (*.bmp)'; ...
                '*.gif', 'GIF files (*.gif)'; ...
                '*.jpg', 'JPEG files (*jpg)'; ...
                '*.png', 'PNG files (*.png)'; ...
                '*.tif', 'TIFF files (*tif)' ...
            });
            if file == 0
                return
            end
            filepath = fullfile(path, file);
            disp(filepath);

            imwrite(app.O, filepath);
        end

        function updateApp1Values(app)
            app.App1InputImage.ImageSource = app.I;

            [m, n] = size(app.I, 1, 2);
            p = m * 2; q = n * 2;

            % Get kernel based on function
            cutoff = app.App1CutoffSlider.Value;
            order = app.App1OrderSlider.Value;
            if app.filtertype == "ideal"
                R = ilpf(cutoff, {p, q});
                G = ilpf(cutoff, {p, q});
                B = ilpf(cutoff, {p, q});
            elseif app.filtertype == "gauss"
                R = glpf(cutoff, {p, q});
                G = glpf(cutoff, {p, q});
                B = glpf(cutoff, {p, q});
            elseif app.filtertype == "butt"
                R = blpf(cutoff, order, {p, q});
                G = blpf(cutoff, order, {p, q});
                B = blpf(cutoff, order, {p, q});
            end

            % Get kernel based on pass filter
            if app.passtype == "high"
                R = 1 - R;
                G = 1 - G;
                B = 1 - B;
            end
            app.O = passfilter(app.I(:,:,1), R);
            app.O(:,:,2) = passfilter(app.I(:,:,2), G);
            app.O(:,:,3) = passfilter(app.I(:,:,3), B);
            
            % Outputs
            app.App1OutputImage.ImageSource = app.O;
            app.App1FileName.Text = app.filename;
        end

        function updateApp2Values(app)
            app.App2InputImage.ImageSource = app.I2;

            % Get values
            CR = app.App2ContrastSlider.Value;
            BR = app.App2BrightnessSlider.Value;

            % Filter brightness
            app.O2 = ftbrightness(app.I2(:,:,1), BR);
            app.O2(:,:,2) = ftbrightness(app.I2(:,:,2), BR);
            app.O2(:,:,3) = ftbrightness(app.I2(:,:,3), BR);

            % Filter contrast
            app.O2(:,:,1) = ftcontrast(app.O2(:,:,1), CR);
            app.O2(:,:,2) = ftcontrast(app.O2(:,:,2), CR);
            app.O2(:,:,3) = ftcontrast(app.O2(:,:,3), CR);

            app.O2 = cast(app.O2, 'uint8');
            
            % Outputs
            app.App2OutputImage.ImageSource = app.O2;
            app.App2FileName.Text = app.filename;
        end

        function updateApp3Values(app)
            selectedButton = app.App3SelectMode.SelectedObject;
            if selectedButton.Text == "Grayscale"
                app.App3IImage.ImageSource = app.rgbtogray(app.I);
                app.App3InputHist.HistType = HistogramType.Grayscale;
                app.App3OutputHist.HistType = HistogramType.Grayscale;

                % Run function
                app.O = histogramEqualization(app.I);
                app.O = cast(app.O, 'uint8');

                % Calc Hist
                hist = histogramz(rgb2gray(app.I));
                app.App3InputHist.Values1 = hist;
                hist = histogramz(app.O);
                app.App3OutputHist.Values1 = hist;

                % Display Image
                app.O(:,:,2) = app.O(:,:,1);
                app.O(:,:,3) = app.O(:,:,1);
                app.App3OImage.ImageSource = app.O;
            else % selectedButton.Text == "Color"
                app.App3IImage.ImageSource = app.I;
                app.App3InputHist.HistType = HistogramType.Color;
                app.App3OutputHist.HistType = HistogramType.Color;

                % Run function
                app.O = histogramEqualization(app.I(:,:,1));
                app.O(:,:,2) = histogramEqualization(app.I(:,:,2));
                app.O(:,:,3) = histogramEqualization(app.I(:,:,3));
                app.O = cast(app.O, 'uint8');

                % Calc Hist
                app.App3InputHist.Values1 = histogramz(app.I(:,:,1));
                app.App3InputHist.Values2 = histogramz(app.I(:,:,2));
                app.App3InputHist.Values3 = histogramz(app.I(:,:,3));
                app.App3OutputHist.Values1 = histogramz(app.O(:,:,1));
                app.App3OutputHist.Values2 = histogramz(app.O(:,:,2));
                app.App3OutputHist.Values3 = histogramz(app.O(:,:,3));

                % Display Image
                app.App3OImage.ImageSource = app.O;
            end
            app.App3InputHist.refresh();
            app.App3OutputHist.refresh();
            app.App3FileName.Text = app.filename;
        end

        
        function updateBasedOnTab(app)
            selectedTab = app.TabGroup.SelectedTab;
            
            if selectedTab == app.Tab1
                app.updateApp1Values();
            elseif selectedTab == app.BrightnessContrastTab
                app.updateApp2Values();
            elseif selectedTab == app.HistogramEqualizationTab
                app.updateApp3Values();
            end
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Selection change function: TabGroup
        function TabGroupSelectionChanged(app, event)
            app.updateBasedOnTab();
        end

        % Button pushed function: App1LoadFileButton
        function App1LoadFileButtonPushed(app, event)
            app.loadImage();
        end

        % Selection changed function: App1FilterPassPanel
        function App1FilterPassPanelSelectionChanged(app, event)
            selectedButton = app.App1FilterPassPanel.SelectedObject;
            
            if selectedButton == app.LowPassButton
                app.passtype = "low";
            elseif selectedButton == app.HighPassButton
                app.passtype = "high";
            end

            app.updateApp1Values();
        end

        % Value changed function: App1FilterTypeDropDown
        function App1FilterTypeDropDownValueChanged(app, event)
            value = app.App1FilterTypeDropDown.Value;
            
            if value == "Ideal"
                app.filtertype = "ideal";
                app.App1OrderSlider.Enable = false;
            elseif value == "Gaussian"
                app.filtertype = "gauss";
                app.App1OrderSlider.Enable = false;
            elseif value == "Butterworth"
                app.filtertype = "butt";
                app.App1OrderSlider.Enable = true;
            end

            app.updateApp1Values();
        end

        % Value changed function: App1CutoffSlider, App1OrderSlider
        function App1Update(app, event)
            app.updateApp1Values();
        end

        % Callback function
        function App2LoadFileButtonPushed(app, event)
            app.loadImage();
        end

        % Button pushed function: App2SaveFileButton
        function App2SaveFileButtonPushed(app, event)
            app.saveImage();
        end

        % Value changed function: App2BrightnessSlider, App2ContrastSlider
        function App2Update(app, event)
            app.updateApp2Values();
        end

        % Callback function
        function App3LoadFileButtonPushed(app, event)
            app.loadImage();
        end

        % Callback function
        function App3SaveFileButtonPushed(app, event)
            app.saveImage();
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.MainGridLayout = uigridlayout(app.UIFigure);
            app.TabGroup = uitabgroup(app.MainGridLayout);
            app.TabGroup.SelectionChangedFcn = createCallbackFcn(app, @TabGroupSelectionChanged, true);
            app.Tab1 = uitab(app.TabGroup);
            app.Tab1.Title = 'Low/High Pass Filter';
            app.GridLayout = uigridlayout(app.Tab1);
            app.App1ControlPanel = uipanel(app.GridLayout);
            app.App1FilterPassPanel = uibuttongroup(app.App1ControlPanel);
            app.App1FilterPassPanel.SelectionChangedFcn = createCallbackFcn(app, @App1FilterPassPanelSelectionChanged, true);
            app.LowPassButton = uiradiobutton(app.App1FilterPassPanel);
            app.LowPassButton.Text = 'Low Pass';
            app.LowPassButton.Value = true;
            app.HighPassButton = uiradiobutton(app.App1FilterPassPanel);
            app.HighPassButton.Text = 'High Pass';
            app.App1FilterPassLabel = uilabel(app.App1ControlPanel);
            app.App1FilterPassLabel.Text = '  Filter Pass ';
            app.App1LoadPanel = uipanel(app.App1ControlPanel);
            app.App1LoadFileButton = uibutton(app.App1LoadPanel, 'push');
            app.App1LoadFileButton.ButtonPushedFcn = createCallbackFcn(app, @App1LoadFileButtonPushed, true);
            app.App1LoadFileButton.Text = 'Load File';
            app.App1FileName = uilabel(app.App1LoadPanel);
            app.App1FileName.Text = 'No File Selected';
            app.FilterTypeDropDownLabel = uilabel(app.App1ControlPanel);
            app.FilterTypeDropDownLabel.Text = 'Filter Type';
            app.App1FilterTypeDropDown = uidropdown(app.App1ControlPanel);
            app.App1FilterTypeDropDown.Items = {'Ideal', 'Gaussian', 'Butterworth'};
            app.App1FilterTypeDropDown.ValueChangedFcn = createCallbackFcn(app, @App1FilterTypeDropDownValueChanged, true);
            app.App1FilterTypeDropDown.Value = 'Ideal';
            app.CutoffSliderLabel = uilabel(app.App1ControlPanel);
            app.CutoffSliderLabel.Text = 'Cutoff';
            app.App1CutoffSlider = uislider(app.App1ControlPanel);
            app.App1CutoffSlider.Limits = [0 500];
            app.App1CutoffSlider.ValueChangedFcn = createCallbackFcn(app, @App1Update, true);
            app.App1CutoffSlider.Value = 500;
            app.OrderSliderLabel = uilabel(app.App1ControlPanel);
            app.OrderSliderLabel.Enable = 'off';
            app.OrderSliderLabel.Text = 'Order';
            app.App1OrderSlider = uislider(app.App1ControlPanel);
            app.App1OrderSlider.Limits = [1 10];
            app.App1OrderSlider.ValueChangedFcn = createCallbackFcn(app, @App1Update, true);
            app.App1OrderSlider.Enable = 'off';
            app.App1OrderSlider.Value = 1;
            app.App1OutputImage = uiimage(app.GridLayout);
            app.App1OutputImage.ImageSource = '1mansion.png';
            app.App1OutputLabel = uilabel(app.GridLayout);
            app.App1OutputLabel.Text = 'Output';
            app.App1InputImage = uiimage(app.GridLayout);
            app.App1InputImage.ImageSource = '1mansion.png';
            app.App1InputLabel = uilabel(app.GridLayout);
            app.App1InputLabel.Text = 'Input';
            app.BrightnessContrastTab = uitab(app.TabGroup);
            app.BrightnessContrastTab.Title = 'Brightness/Contrast';
            app.GridLayout2 = uigridlayout(app.BrightnessContrastTab);
            app.App2ControlPanel = uipanel(app.GridLayout2);
            app.App2LoadPanel = uipanel(app.App2ControlPanel);
            app.App2LoadFileButton = uibutton(app.App2LoadPanel, 'push');
            app.App2LoadFileButton.Text = 'Load File';
            app.App2FileName = uilabel(app.App2LoadPanel);
            app.App2FileName.Text = 'No File Selected';
            app.App2SaveFileButton = uibutton(app.App2LoadPanel, 'push');
            app.App2SaveFileButton.ButtonPushedFcn = createCallbackFcn(app, @App2SaveFileButtonPushed, true);
            app.App2SaveFileButton.Text = 'Save File';
            app.BrightnessLabel = uilabel(app.App2ControlPanel);
            app.BrightnessLabel.Text = 'Brightness';
            app.App2BrightnessSlider = uislider(app.App2ControlPanel);
            app.App2BrightnessSlider.Limits = [0 5];
            app.App2BrightnessSlider.ValueChangedFcn = createCallbackFcn(app, @App2Update, true);
            app.App2BrightnessSlider.Value = 1.5;
            app.ContrastLabel = uilabel(app.App2ControlPanel);
            app.ContrastLabel.Text = 'Contrast';
            app.App2ContrastSlider = uislider(app.App2ControlPanel);
            app.App2ContrastSlider.Limits = [0 5];
            app.App2ContrastSlider.ValueChangedFcn = createCallbackFcn(app, @App2Update, true);
            app.App2ContrastSlider.Value = 0.8;
            app.App2OutputImage = uiimage(app.GridLayout2);
            app.App2OutputImage.ImageSource = '3after.png';
            app.App2OutputLabel = uilabel(app.GridLayout2);
            app.App2OutputLabel.Text = 'Output';
            app.App2InputImage = uiimage(app.GridLayout2);
            app.App2InputImage.ImageSource = '3before.png';
            app.App2InputLabel = uilabel(app.GridLayout2);
            app.App2InputLabel.Text = 'Input';
            app.HistogramEqualizationTab = uitab(app.TabGroup);
            app.HistogramEqualizationTab.Title = 'Histogram Equalization';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = main

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end